classdef DebugApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label_sysTime  matlab.ui.control.Label
        Label          matlab.ui.control.Label
    end

    
    properties (Access = private)
        mapFig % Description
        gx
    end
    
    methods (Access = public)
        
        function display_update_systime(app,time)
            app.Label_sysTime.Text=time;
        end
    end
    
    methods (Access = private)
        
        function drawMap(app)
            latShanghai = 31.19590;
            lonShanghai = 121.34113;
            latAnchorage = 31.97;
            lonAnchorage = 118.80;

            
            geoplot(app.gx,[latShanghai latAnchorage],[lonShanghai lonAnchorage],'-*')
            %geolimits([30 33],[118 122])
            %geolimits('auto') 
            geobasemap(app.gx,'streets')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.mapFig=uifigure(app.UIFigure);
            app.gx = geoaxes(app.mapFig);
            drawMap(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '系统时间';
            app.Label_sysTime = uilabel(app.UIFigure);
            app.Label_sysTime.Text = {'Label_sysTime'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DebugApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end